classdef appRele < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OmegaEditFieldLabel  matlab.ui.control.Label
        OmegaEditField       matlab.ui.control.EditField
        Button               matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           ButtonPushed(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = str2double(app.OmegaEditField.Value);
            x = 0:0.1:value + 3*value;
            cdf = raylcdf(x,value);
            pdf = raylpdf(x, value);
            plot(app.UIAxes, x, cdf);
            plot(app.UIAxes2, x, pdf);
            
            if app.OmegaEditField.Visible == 'off'
                app.OmegaEditField.Visible = 'on';
            else
                app.OmegaEditField.Visible = 'off';
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OmegaEditFieldLabel = uilabel(app.UIFigure);
            app.OmegaEditFieldLabel.Text = 'Omega';
            app.OmegaEditField = uieditfield(app.UIFigure, 'text');
            app.OmegaEditField.Value = '1';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Функция распределения')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Функция плотности распределения')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appRele

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end